classdef roboticManipulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManipulatorRobotHandUIFigure  matlab.ui.Figure
        MainPanel                     matlab.ui.container.Panel
        DegreesLabel_6                matlab.ui.control.Label
        DegreesLabel_5                matlab.ui.control.Label
        DegreesLabel_4                matlab.ui.control.Label
        DegreesLabel_3                matlab.ui.control.Label
        DegreesLabel_2                matlab.ui.control.Label
        DegreesLabel_1                matlab.ui.control.Label
        DigitalKnobsCheckBox          matlab.ui.control.CheckBox
        EndEffectorKnob               matlab.ui.control.Knob
        EndEffectorKnobLabel          matlab.ui.control.Label
        J5Knob                        matlab.ui.control.Knob
        J5KnobLabel                   matlab.ui.control.Label
        J4Knob                        matlab.ui.control.Knob
        J4KnobLabel                   matlab.ui.control.Label
        J3Knob                        matlab.ui.control.Knob
        J3KnobLabel                   matlab.ui.control.Label
        J2Knob                        matlab.ui.control.Knob
        J2KnobLabel                   matlab.ui.control.Label
        J1Knob                        matlab.ui.control.Knob
        J1KnobLabel                   matlab.ui.control.Label
        StandbyModeButton             matlab.ui.control.StateButton
        ManualControlButton           matlab.ui.control.StateButton
        PortPanel                     matlab.ui.container.Panel
        labelPort                     matlab.ui.control.Label
        BoardPanel                    matlab.ui.container.Panel
        labelBoard                    matlab.ui.control.Label
    end

    properties (Access = private)
        % a = arduino('COM9', 'Uno', 'Libraries', 'Servo');
        a;
        servos;
        servoPins = {'D3', 'D5', 'D6', 'D9', 'D10', 'D11'};
        potPins = {'A0', 'A1', 'A2', 'A3', 'A4', 'A5'};
    end
    
    methods (Access = private)
        function clearServos(app)
            if ~isempty(app.servos)
                for i = 1:length(app.servos)
                    if ~isempty(app.servos{i}) && isvalid(app.servos{i})
                        delete app.servos{i};
                    end
                end
            end
            app.servos = {};
        end
        
        function arduinoReset(app)
            if isempty(app.a) || ~isvalid(app.a)
                try
                    app.a = arduino('COM9', 'Uno', 'Libraries', 'Servo');
                    disp('Arduino Initialized');
                catch e
                    uialert(app.ManipulatorRobotHandUIFigure, e.message, 'Error');
                    return;
                end
            end
        end
        
        function knobValue(app, event, pinNumber, labelNumber)
            if app.DigitalKnobsCheckBox.Value == true
                knobLabels = {app.DegreesLabel_1, app.DegreesLabel_2, app.DegreesLabel_3, app.DegreesLabel_4, app.DegreesLabel_5, app.DegreesLabel_6};
                app.clearServos();
                pause(0.1);
                
                changingValue = event.Value;
        
                knobLabels{labelNumber}.Text = char(string(round(changingValue)) + " Degrees");
                writePWMDutyCycle(app.a, app.servoPins{pinNumber}, round(changingValue) / 255.0);
            end
        end
        
        %{
        function printValue(app)
            for i = 1:app.numServos
                app.servos{i} = servo(app.a, app.servoPins{i}, 'MinPulseDuration', 700e-6, 'MaxPulseDuration', 2300e-6);
            end
            
            currentPos = readPosition(app.servos{i}) * 180;
            pause(0.1);
            fprintf('Servo %d is at %d degrees\n', i, round(currentPos));
        end
        %}
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.a = arduino('COM9', 'Uno', 'Libraries', 'Servo');
                set(app.labelBoard,'Text',app.a.Board);
                set(app.labelPort,'Text',app.a.Port);
            catch e
                uialert(app.ManipulatorRobotHandUIFigure, e.message, 'ERROR');
            end
        end

        % Close request function: ManipulatorRobotHandUIFigure
        function ManipulatorRobotHandUIFigureCloseRequest(app, event)
            app.clearServos;
            if ~isempty(app.a) && isvalid(app.a)
                clear app.a;
            end
            delete(app.ManipulatorRobotHandUIFigure);
        end

        % Value changed function: ManualControlButton
        function ManualControlButtonValueChanged(app, event)
            app.arduinoReset();
            
            app.clearServos();
            pause(1.5);
            
            manual = app.ManualControlButton.Value;
            zero = app.StandbyModeButton.Value;
            
            numPots = length(app.potPins);
            numServos = length(app.servoPins);
            app.servos = cell(1, numServos);
            
            
            for i = 1:numServos
               app.servos{i} = servo(app.a, app.servoPins{i}, 'MinPulseDuration', 700e-6, 'MaxPulseDuration', 2300e-6);
            end
            
             if manual == true && zero == false
                 while manual == true
                     for i = 1:numPots
                         potVal = readVoltage(app.a, app.potPins{i});
                         valConversion = (potVal / 5.0);
                         writePosition(app.servos{i}, valConversion);
                         % app.printValue();
                         currentPos = readPosition(app.servos{i}) * 180;
                         pause(0.1);
                         fprintf('Servo %d is at %d degrees\n', i, round(currentPos));
                     end
                     manual = app.ManualControlButton.Value;
                 end
             end
        end

        % Value changed function: StandbyModeButton
        function StandbyModeButtonValueChanged(app, event)
            app.arduinoReset();
            
            app.clearServos();
            pause(1.5);
            
            zero = app.StandbyModeButton.Value;
            manual = app.ManualControlButton.Value;
            
            numServos = length(app.servoPins);
            
            if zero == true && manual == false
                for i = 1:numServos
                    app.servos{i} = servo(app.a, app.servoPins{i}, 'MinPulseDuration', 700e-6, 'MaxPulseDuration', 2300e-6);
                    % app.printValue();
                    writePosition(app.servos{i}, 0);
                    currentPos = readPosition(app.servos{i}) * 180;
                    fprintf('Servo %d is at %d degrees\n', i, round(currentPos));
                end
            end
        end

        % Value changing function: J1Knob
        function J1KnobValueChanging(app, event)
            app.knobValue(event, 1, 1);
        end

        % Value changing function: J2Knob
        function J2KnobValueChanging(app, event)
            app.knobValue(event, 2, 2);
        end

        % Value changing function: J3Knob
        function J3KnobValueChanging(app, event)
            app.knobValue(event, 3, 3);
        end

        % Value changing function: J4Knob
        function J4KnobValueChanging(app, event)
            app.knobValue(event, 4, 4);
        end

        % Value changing function: J5Knob
        function J5KnobValueChanging(app, event)
            app.knobValue(event, 5, 5);
        end

        % Value changing function: EndEffectorKnob
        function EndEffectorKnobValueChanging(app, event)
            app.knobValue(event, 6, 6);
        end

        % Value changed function: DigitalKnobsCheckBox
        function DigitalKnobsCheckBoxValueChanged(app, event)
            app.DigitalKnobsCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManipulatorRobotHandUIFigure = uifigure('Visible', 'off');
            app.ManipulatorRobotHandUIFigure.Name = 'Manipulator Robot Hand';
            app.ManipulatorRobotHandUIFigure.Icon = 'logo.jpeg';
            app.ManipulatorRobotHandUIFigure.CloseRequestFcn = createCallbackFcn(app, @ManipulatorRobotHandUIFigureCloseRequest, true);
            app.MainPanel = uipanel(app.ManipulatorRobotHandUIFigure);
            app.MainPanel.Title = 'Main';
            app.BoardPanel = uipanel(app.MainPanel);
            app.BoardPanel.Title = 'Board';
            app.labelBoard = uilabel(app.BoardPanel);
            app.labelBoard.Text = '';
            app.PortPanel = uipanel(app.MainPanel);
            app.PortPanel.Title = 'Port';
            app.labelPort = uilabel(app.PortPanel);
            app.labelPort.Text = '';
            app.ManualControlButton = uibutton(app.MainPanel, 'state');
            app.ManualControlButton.ValueChangedFcn = createCallbackFcn(app, @ManualControlButtonValueChanged, true);
            app.ManualControlButton.Text = 'Manual Control';
            app.StandbyModeButton = uibutton(app.MainPanel, 'state');
            app.StandbyModeButton.ValueChangedFcn = createCallbackFcn(app, @StandbyModeButtonValueChanged, true);
            app.StandbyModeButton.Text = 'Standby Mode';
            app.J1KnobLabel = uilabel(app.MainPanel);
            app.J1KnobLabel.Text = 'J1';
            app.J1Knob = uiknob(app.MainPanel, 'continuous');
            app.J1Knob.Limits = [0 180];
            app.J1Knob.ValueChangingFcn = createCallbackFcn(app, @J1KnobValueChanging, true);
            app.J2KnobLabel = uilabel(app.MainPanel);
            app.J2KnobLabel.Text = 'J2';
            app.J2Knob = uiknob(app.MainPanel, 'continuous');
            app.J2Knob.Limits = [0 180];
            app.J2Knob.ValueChangingFcn = createCallbackFcn(app, @J2KnobValueChanging, true);
            app.J3KnobLabel = uilabel(app.MainPanel);
            app.J3KnobLabel.Text = 'J3';
            app.J3Knob = uiknob(app.MainPanel, 'continuous');
            app.J3Knob.Limits = [0 180];
            app.J3Knob.ValueChangingFcn = createCallbackFcn(app, @J3KnobValueChanging, true);
            app.J4KnobLabel = uilabel(app.MainPanel);
            app.J4KnobLabel.Text = 'J4';
            app.J4Knob = uiknob(app.MainPanel, 'continuous');
            app.J4Knob.Limits = [0 180];
            app.J4Knob.ValueChangingFcn = createCallbackFcn(app, @J4KnobValueChanging, true);
            app.J5KnobLabel = uilabel(app.MainPanel);
            app.J5KnobLabel.Text = 'J5';
            app.J5Knob = uiknob(app.MainPanel, 'continuous');
            app.J5Knob.Limits = [0 180];
            app.J5Knob.ValueChangingFcn = createCallbackFcn(app, @J5KnobValueChanging, true);
            app.EndEffectorKnobLabel = uilabel(app.MainPanel);
            app.EndEffectorKnobLabel.Text = 'End-Effector';
            app.EndEffectorKnob = uiknob(app.MainPanel, 'continuous');
            app.EndEffectorKnob.Limits = [0 180];
            app.EndEffectorKnob.ValueChangingFcn = createCallbackFcn(app, @EndEffectorKnobValueChanging, true);
            app.DigitalKnobsCheckBox = uicheckbox(app.MainPanel);
            app.DigitalKnobsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DigitalKnobsCheckBoxValueChanged, true);
            app.DigitalKnobsCheckBox.Text = 'Digital Knobs';
            app.DegreesLabel_1 = uilabel(app.MainPanel);
            app.DegreesLabel_1.Text = 'Degrees';
            app.DegreesLabel_2 = uilabel(app.MainPanel);
            app.DegreesLabel_2.Text = 'Degrees';
            app.DegreesLabel_3 = uilabel(app.MainPanel);
            app.DegreesLabel_3.Text = 'Degrees';
            app.DegreesLabel_4 = uilabel(app.MainPanel);
            app.DegreesLabel_4.Text = 'Degrees';
            app.DegreesLabel_5 = uilabel(app.MainPanel);
            app.DegreesLabel_5.Text = 'Degrees';
            app.DegreesLabel_6 = uilabel(app.MainPanel);
            app.DegreesLabel_6.Text = 'Degrees';
            % Show the figure after all components are created
            app.ManipulatorRobotHandUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roboticManipulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManipulatorRobotHandUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManipulatorRobotHandUIFigure)
        end
    end
end